classdef odevGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SesTanmaUIFigure  matlab.ui.Figure
        ysaolustur        matlab.ui.control.Button
        dosyasec          matlab.ui.control.Button
        yenisesekle       matlab.ui.control.Button
        bul               matlab.ui.control.Button
        lbl1              matlab.ui.control.Label
        sonuc             matlab.ui.control.Label
    end

    
    properties (Access = public)
        dataDir
        adsTrain;
        dsInfo;
        ort;
        ssapma;
        dosya;
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            evalin( 'base', 'clearvars *' ); %Workspace'i temizleme
            app.dataDir = HelperAN4Download; %database yoksa indirme
            app.adsTrain = audioDatastore(app.dataDir,'IncludeSubfolders',true, ...
            'FileExtensions','.flac', ...
            'LabelSource','foldernames');%Audiodatastore sınıfından bir obje oluşturma
            [~, app.dsInfo] = read(app.adsTrain);
            %sound(sampleTrain,app.dsInfo.SampleRate) %Örnek ses oynatma
            reset(app.adsTrain);
        end

        % Button pushed function: bul
        function bulPushed(app, event)
            %Konuşan kişiyi bulma
            konusan = konusanibul(app.dosya,app.ort,app.ssapma);
            %Konuşan kişinin adını yazdırma
            sonuc = konusmaci(konusan);
            app.sonuc.Text = sonuc;
        end

        % Button pushed function: ysaolustur
        function ysaolusturPushed(app, event)
            fs = app.dsInfo.SampleRate;
            windowLength = round(0.03*fs);
            overlapLength = round(0.025*fs);
            
            features = [];
            labels = [];
            while hasdata(app.adsTrain)
                [audioIn,app.dsInfo] = read(app.adsTrain);
                
                melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
                f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
                feat = [melC,f0];
                %belirli bir zaman aralığında çıkan sesin konuşma olup
                %olmadığını bulan fonksiyon
                voicedSpeech = isVoicedSpeech(audioIn,fs,windowLength,overlapLength);
                
                feat(~voicedSpeech,:) = [];
                label = repelem(app.dsInfo.Label,size(feat,1));
                %matrislere yeni oluşan matrisin eklenmesi
                features = [features;feat]; 
                labels = [labels,label]; 
            end
            
            
            app.ort = mean(features,1); %Ort 
            app.ssapma = std(features,[],1); %S.sapma
            features = (features-app.ort)./app.ssapma;
            
            assignin('base','features',features);
            labels = transpose(labels); %labelların transpose edildiği kısım
            labels = onehotencode(labels,2);%labelların 0-1 aralığına hesaplatma
            
            assignin('base','labels' ,labels);
            
           
            if(~exist('myNeuralNetworkFunction'))
                nprtool;
            end
               
        end

        % Button pushed function: dosyasec
        function dosyasecPushed(app, event)
            %File Select
            [adosya,dosyaad]=uigetfile('C:\Users\Gurkan\Desktop\örüntü_tanıma\an4\wav\flacData\*.flac');   
            app.dosya = strcat(dosyaad,adosya); %parametreleri birleştirip dosya yolunu bulma
            
        end

        % Button pushed function: yenisesekle
        function yeniseseklePushed(app, event)
            [adosya,dosyaad]=uigetfile('C:\Users\Gurkan\Desktop\sesle\*.flac'); 
            app.dosya = strcat(dosyaad,adosya); %parametreleri birleştirip dosya yolunu bulma
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SesTanmaUIFigure = uifigure('Visible', 'off');
            app.SesTanmaUIFigure.Name = 'Ses Tanıma';
            app.ysaolustur = uibutton(app.SesTanmaUIFigure, 'push');
            app.ysaolustur.ButtonPushedFcn = createCallbackFcn(app, @ysaolusturPushed, true);
            app.ysaolustur.Text = 'Sinir Ağı Oluştur';
            app.dosyasec = uibutton(app.SesTanmaUIFigure, 'push');
            app.dosyasec.ButtonPushedFcn = createCallbackFcn(app, @dosyasecPushed, true);
            app.dosyasec.Text = 'Ses Dosyası Seç';
            app.yenisesekle = uibutton(app.SesTanmaUIFigure, 'push');
            app.yenisesekle.ButtonPushedFcn = createCallbackFcn(app, @yeniseseklePushed, true);
            app.yenisesekle.Text = 'Başka Ses Ekle';
            app.bul = uibutton(app.SesTanmaUIFigure, 'push');
            app.bul.ButtonPushedFcn = createCallbackFcn(app, @bulPushed, true);
            app.bul.Text = 'Konuçmacı Bul';
            app.lbl1 = uilabel(app.SesTanmaUIFigure);
            app.lbl1.Text = 'Konuşan Kişi';
            app.sonuc = uilabel(app.SesTanmaUIFigure);
            app.sonuc.Text = '';
            % Show the figure after all components are created
            app.SesTanmaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = odevGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SesTanmaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SesTanmaUIFigure)
        end
    end
end